classdef prototype1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        DataTab                         matlab.ui.container.Tab
        PickDataSetPanel                matlab.ui.container.Panel
        DataDropDownLabel               matlab.ui.control.Label
        DataDropDown                    matlab.ui.control.DropDown
        FeaturesTab                     matlab.ui.container.Tab
        FeatureToExamineDropDownLabel   matlab.ui.control.Label
        FeatureToExamineDropDown        matlab.ui.control.DropDown
        FeatureEngineeringPanel         matlab.ui.container.Panel
        CapLargeandSmallValuesCheckBox  matlab.ui.control.CheckBox
        BinDataintocategoriesCheckBox   matlab.ui.control.CheckBox
        CombinewithotherpotentialfeaturesCheckBox  matlab.ui.control.CheckBox
        ModelTab                        matlab.ui.container.Tab
        x4EditFieldLabel                matlab.ui.control.Label
        x4EditField                     matlab.ui.control.NumericEditField
        BuildOutYourPolynomialEstimateLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        x3EditFieldLabel                matlab.ui.control.Label
        x3EditField                     matlab.ui.control.NumericEditField
        x2EditFieldLabel                matlab.ui.control.Label
        x2EditField                     matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        xEditFieldLabel                 matlab.ui.control.Label
        xEditField                      matlab.ui.control.NumericEditField
        Label_3                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        yLabel                          matlab.ui.control.Label
        TrainTab                        matlab.ui.container.Tab
        RightPanel                      matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load('housingData.mat', 'housingData')
            VarNames = housingData.Properties.VariableNames;
            app.FeatureToExamineDropDown.Items = VarNames(1:end-1);
        end

        % Value changed function: DataDropDown
        function DataDropDownValueChanged(app, event)
            value = app.DataDropDown.Value;
            if strcmp(value , 'Housing')
                VarNames = housingData.Properties.VariableNames;
                app.FeatureToExamineDropDown.Items = VarNames(1:end-1);
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {503, 503};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {358, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.PickDataSetPanel = uipanel(app.DataTab);
            app.PickDataSetPanel.Title = 'Pick Data Set';
            app.DataDropDownLabel = uilabel(app.PickDataSetPanel);
            app.DataDropDownLabel.Text = 'Data';
            app.DataDropDown = uidropdown(app.PickDataSetPanel);
            app.DataDropDown.Items = {'Housing', 'Hospital'};
            app.DataDropDown.ValueChangedFcn = createCallbackFcn(app, @DataDropDownValueChanged, true);
            app.DataDropDown.Value = 'Housing';
            app.FeaturesTab = uitab(app.TabGroup);
            app.FeaturesTab.Title = 'Features';
            app.FeatureToExamineDropDownLabel = uilabel(app.FeaturesTab);
            app.FeatureToExamineDropDownLabel.Text = 'Feature To Examine';
            app.FeatureToExamineDropDown = uidropdown(app.FeaturesTab);
            app.FeatureEngineeringPanel = uipanel(app.FeaturesTab);
            app.FeatureEngineeringPanel.Title = 'Feature Engineering';
            app.CapLargeandSmallValuesCheckBox = uicheckbox(app.FeatureEngineeringPanel);
            app.CapLargeandSmallValuesCheckBox.Text = 'Cap Large and Small Values';
            app.BinDataintocategoriesCheckBox = uicheckbox(app.FeatureEngineeringPanel);
            app.BinDataintocategoriesCheckBox.Text = 'Bin Data into categories';
            app.CombinewithotherpotentialfeaturesCheckBox = uicheckbox(app.FeatureEngineeringPanel);
            app.CombinewithotherpotentialfeaturesCheckBox.Text = 'Combine with other potential features';
            app.ModelTab = uitab(app.TabGroup);
            app.ModelTab.Title = 'Model';
            app.x4EditFieldLabel = uilabel(app.ModelTab);
            app.x4EditFieldLabel.Text = 'x^4';
            app.x4EditField = uieditfield(app.ModelTab, 'numeric');
            app.BuildOutYourPolynomialEstimateLabel = uilabel(app.ModelTab);
            app.BuildOutYourPolynomialEstimateLabel.Text = 'Build Out Your Polynomial Estimate';
            app.Label = uilabel(app.ModelTab);
            app.Label.Text = '+';
            app.x3EditFieldLabel = uilabel(app.ModelTab);
            app.x3EditFieldLabel.Text = 'x^3';
            app.x3EditField = uieditfield(app.ModelTab, 'numeric');
            app.x2EditFieldLabel = uilabel(app.ModelTab);
            app.x2EditFieldLabel.Text = 'x^2';
            app.x2EditField = uieditfield(app.ModelTab, 'numeric');
            app.Label_2 = uilabel(app.ModelTab);
            app.Label_2.Text = '+';
            app.xEditFieldLabel = uilabel(app.ModelTab);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.ModelTab, 'numeric');
            app.Label_3 = uilabel(app.ModelTab);
            app.Label_3.Text = '+';
            app.Label_4 = uilabel(app.ModelTab);
            app.Label_4.Text = '+';
            app.EditField = uieditfield(app.ModelTab, 'text');
            app.yLabel = uilabel(app.ModelTab);
            app.yLabel.Text = '= y';
            app.TrainTab = uitab(app.TabGroup);
            app.TrainTab.Title = 'Train';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = prototype1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end