classdef prototype2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        OverallGridLayout              matlab.ui.container.GridLayout
        RightGridLayout                matlab.ui.container.GridLayout
        ModelGridLayout                matlab.ui.container.GridLayout
        BuildaPolynomialEstimatePanel  matlab.ui.container.Panel
        YLabel                         matlab.ui.control.Label
        Plus3                          matlab.ui.control.Label
        Plus2                          matlab.ui.control.Label
        Plus1                          matlab.ui.control.Label
        PlusK                          matlab.ui.control.Label
        x4EditFieldLabel               matlab.ui.control.Label
        x4                             matlab.ui.control.NumericEditField
        x3EditFieldLabel               matlab.ui.control.Label
        x3                             matlab.ui.control.NumericEditField
        x2EditFieldLabel               matlab.ui.control.Label
        x2                             matlab.ui.control.NumericEditField
        xEditFieldLabel                matlab.ui.control.Label
        x                              matlab.ui.control.NumericEditField
        PlotEstimateButton             matlab.ui.control.Button
        Constant                       matlab.ui.control.NumericEditField
        UseMachineLearningtoImproveYourEstimatePanel  matlab.ui.container.Panel
        ErrorMeasureDropDownLabel      matlab.ui.control.Label
        ErrorMeasureDropDown           matlab.ui.control.DropDown
        SetLearningRateEditFieldLabel  matlab.ui.control.Label
        SetLearningRateEditField       matlab.ui.control.NumericEditField
        TRAINButton                    matlab.ui.control.Button
        InfoaboutlearningrateRangeLabel  matlab.ui.control.Label
        IterationLabel                 matlab.ui.control.Label
        Iteration                      matlab.ui.control.NumericEditField
        ErrorEditFieldLabel            matlab.ui.control.Label
        ErrorOut                       matlab.ui.control.NumericEditField
        ThetaLabel                     matlab.ui.control.Label
        ThetaOut                       matlab.ui.control.NumericEditField
        OptimizationMethodButtonGroup  matlab.ui.container.ButtonGroup
        IterationsButton               matlab.ui.control.RadioButton
        ErrorButton                    matlab.ui.control.RadioButton
        DesiredIterationsEditFieldLabel  matlab.ui.control.Label
        DesiredIterations              matlab.ui.control.NumericEditField
        DesiredErrorEditFieldLabel     matlab.ui.control.Label
        DesiredErrorThreshold          matlab.ui.control.NumericEditField
        UIAxes                         matlab.ui.control.UIAxes
        TabGroup                       matlab.ui.container.TabGroup
        DataTab                        matlab.ui.container.Tab
        DatasetDropDownLabel           matlab.ui.control.Label
        DatasetDropDown                matlab.ui.control.DropDown
        PickyourdatasetforanalysisLabel  matlab.ui.control.Label
        NoteOnceyouchooseaLabel        matlab.ui.control.Label
        datasetdontchangeitforLabel    matlab.ui.control.Label
        thedurationofyourLabel         matlab.ui.control.Label
        analysisLabel                  matlab.ui.control.Label
        FeatureSelectioTab             matlab.ui.container.Tab
        FeaturestoExamineLabel         matlab.ui.control.Label
        firstFeature                   matlab.ui.control.DropDown
        CombinewithAnotherLabel        matlab.ui.control.Label
        FeatureOptionalLabel           matlab.ui.control.Label
        secondFeature                  matlab.ui.control.DropDown
        ScaleFeatureLabel              matlab.ui.control.Label
        scaleFeature                   matlab.ui.control.DropDown
        WhattoPlotLabel                matlab.ui.control.Label
        plotOptions                    matlab.ui.control.DropDown
        OperationDropDownLabel         matlab.ui.control.Label
        combineOperation               matlab.ui.control.DropDown
    end

    
    properties (Access = public)
         metaData
         masterFeature
    end
    
    methods (Access = public)
        %Devyn 5/20 Push check
        function update_plot(app)
            firstFeaturePlot = app.firstFeature.Value;
            secondFeaturePlot = app.secondFeature.Value;
            scaleFeaturePlot = app.scaleFeature.Value;
            combineOperationPlot = app.combineOperation.Value;
            
            if strcmp(firstFeaturePlot, 'Population in Neighborhood')
                firstFeaturePlot = app.metaData.population;
            elseif strcmp(firstFeaturePlot, 'Median Income')
                firstFeaturePlot = app.metaData.income;
            elseif strcmp(firstFeaturePlot, 'Number of Bedrooms')
                firstFeaturePlot = app.metaData.bedrooms;
            else
                firstFeaturePlot = app.metaData.sizeFamily;
            end
            
            switch secondFeaturePlot
                case 'Population in Neighborhood'
                    secondFeaturePlot = app.metaData.population;
                 
                case 'Median Income'
                    secondFeaturePlot  = app.metaData.income;
                 
                case 'Number of Bedrooms'
                    secondFeaturePlot = app.metaData.bedrooms;
                otherwise
                    secondFeaturePlot = app.metaData.sizeFamily;
            end
            
            switch scaleFeaturePlot
                case 'Log'
                    firstFeaturePlot = log(firstFeaturePlot);
                case 'Normalize'
                    firstFeaturePlot = normalize(firstFeaturePlot);
            end
            
            switch combineOperationPlot
                case 'Add'
                    app.masterFeature = firstFeaturePlot + secondFeaturePlot;
                case 'Subtract'
                    app.masterFeature = firstFeaturePlot - secondFeaturePlot;
                case 'Divide'
                    app.masterFeature = firstFeaturePlot./secondFeaturePlot;
                case 'Multiply'
                    app.masterFeature = firstFeaturePlot.*secondFeaturePlot;
                otherwise
                    app.masterFeature = firstFeaturePlot;
            end
            
            if strcmp(app.plotOptions.Value, 'Feature Only')
                sortedFeature = sort(app.masterFeature);
                plot(app.UIAxes, sortedFeature,'ro', 'MarkerSize',3)
            else
                plot(app.UIAxes, app.masterFeature, app.metaData.housePrice,'ro', 'MarkerSize',3)
                
            end
            
           
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % load in the data
            addpath('data\');
            load housingData.mat housingData
            app.metaData = housingData;
        end

        % Callback function
        function PlotButtonPushed(app, event)
            update_plot(app);
        end

        % Value changed function: firstFeature
        function firstFeatureValueChanged(app, event)
            %value = app.firstFeature.Value;
            update_plot(app);
        end

        % Value changed function: scaleFeature
        function scaleFeatureValueChanged(app, event)
            %value = app.scaleFeature.Value;
            update_plot(app);
        end

        % Value changed function: secondFeature
        function secondFeatureValueChanged(app, event)
            %value = app.secondFeature.Value;
            update_plot(app);
        end

        % Value changed function: combineOperation
        function combineOperationValueChanged(app, event)
            %value = app.combineOperation.Value;
            update_plot(app);
        end

        % Value changed function: plotOptions
        function plotOptionsValueChanged(app, event)
            %value = app.plotOptions.Value;
            update_plot(app);
        end

        % Button pushed function: PlotEstimateButton
        function PlotEstimateButtonPushed(app, event)
            update_plot(app)
            hold(app.UIAxes,'on')
            %define polynomial based on user input
            K=[app.x4.Value app.x3.Value app.x2.Value app.x.Value app.Constant.Value];
            t=0:1:1000;
            
            %plot based on editfield values
            plot(app.UIAxes,t,polyval(K,t), 'k' ,'LineWidth',1.2, 'LineStyle',"--");
            hold(app.UIAxes,'off')
            drawnow();
            
        end

        % Button pushed function: TRAINButton
        function TRAINButtonPushed(app, event)
            %Maybe make learning rate into a knob so the user has context
            %for values
            
            %update_plot(app)
            %hold(app.UIAxes,'on')
            
            theta = zeros(2, 1); 
            alpha=app.SetLearningRateEditField.Value;
            
            y=app.metaData.housePrice;
            m = length(y);
            X = [ones(m, 1), app.masterFeature]; %I might not be able to access this?
            
            
            %initializing
            J = 1000000; %just to initialize
            %J_Min=J;
        
            if app.ErrorButton.Value
                
                while J>app.DesiredErrorThreshold
                    h_x = X*theta;    %compute h_theta(x) = theta^T * x
                    theta = theta - alpha/m*(X' * (h_x - y));
 
                    J = sum((h_x - y) .^ 2)/2/m;  %Taken from compute Cost

                    if J<J_Min
                        %Jmin=J;
                    end
                    pause(1);
                end
           
            elseif app.IterationsButton.Value

                for i=1:app.DesiredIterations.Value
                    h_x = X*theta;    %compute h_theta(x) = theta^T * x
                    theta = theta - alpha/m*(X' * (h_x - y));
 
                    J = sum((h_x - y) .^ 2)/2/m;  %Taken from compute Cost
                    app.ErrorOut.Value=J;
                    %app.ThetaOut.Value=theta;
                    app.Iteration.Value=i;
                    plot(app.UIAxes, X(:,2), X*theta, '-')
                    hold(app.UIAxes,'on')
                    update_plot(app)
                    pause(.5);
                    hold(app.UIAxes,'off')
                end
            end        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OverallGridLayout = uigridlayout(app.UIFigure);
            app.RightGridLayout = uigridlayout(app.OverallGridLayout);
            app.ModelGridLayout = uigridlayout(app.RightGridLayout);
            app.BuildaPolynomialEstimatePanel = uipanel(app.ModelGridLayout);
            app.BuildaPolynomialEstimatePanel.Title = 'Build a Polynomial Estimate';
            app.YLabel = uilabel(app.BuildaPolynomialEstimatePanel);
            app.YLabel.Text = 'Y=';
            app.Plus3 = uilabel(app.BuildaPolynomialEstimatePanel);
            app.Plus3.Text = '+';
            app.Plus2 = uilabel(app.BuildaPolynomialEstimatePanel);
            app.Plus2.Text = '+';
            app.Plus1 = uilabel(app.BuildaPolynomialEstimatePanel);
            app.Plus1.Text = '+';
            app.PlusK = uilabel(app.BuildaPolynomialEstimatePanel);
            app.PlusK.Text = '+';
            app.x4EditFieldLabel = uilabel(app.BuildaPolynomialEstimatePanel);
            app.x4EditFieldLabel.Text = 'x^4';
            app.x4 = uieditfield(app.BuildaPolynomialEstimatePanel, 'numeric');
            app.x3EditFieldLabel = uilabel(app.BuildaPolynomialEstimatePanel);
            app.x3EditFieldLabel.Text = 'x^3';
            app.x3 = uieditfield(app.BuildaPolynomialEstimatePanel, 'numeric');
            app.x2EditFieldLabel = uilabel(app.BuildaPolynomialEstimatePanel);
            app.x2EditFieldLabel.Text = 'x^2';
            app.x2 = uieditfield(app.BuildaPolynomialEstimatePanel, 'numeric');
            app.xEditFieldLabel = uilabel(app.BuildaPolynomialEstimatePanel);
            app.xEditFieldLabel.Text = 'x';
            app.x = uieditfield(app.BuildaPolynomialEstimatePanel, 'numeric');
            app.PlotEstimateButton = uibutton(app.BuildaPolynomialEstimatePanel, 'push');
            app.PlotEstimateButton.ButtonPushedFcn = createCallbackFcn(app, @PlotEstimateButtonPushed, true);
            app.PlotEstimateButton.Text = 'Plot Estimate';
            app.Constant = uieditfield(app.BuildaPolynomialEstimatePanel, 'numeric');
            app.UseMachineLearningtoImproveYourEstimatePanel = uipanel(app.ModelGridLayout);
            app.UseMachineLearningtoImproveYourEstimatePanel.Title = 'Use Machine Learning to Improve Your Estimate';
            app.ErrorMeasureDropDownLabel = uilabel(app.UseMachineLearningtoImproveYourEstimatePanel);
            app.ErrorMeasureDropDownLabel.Text = 'Error Measure';
            app.ErrorMeasureDropDown = uidropdown(app.UseMachineLearningtoImproveYourEstimatePanel);
            app.ErrorMeasureDropDown.Items = {'RSME', 'MSE'};
            app.ErrorMeasureDropDown.Value = 'RSME';
            app.SetLearningRateEditFieldLabel = uilabel(app.UseMachineLearningtoImproveYourEstimatePanel);
            app.SetLearningRateEditFieldLabel.Text = 'Set Learning Rate';
            app.SetLearningRateEditField = uieditfield(app.UseMachineLearningtoImproveYourEstimatePanel, 'numeric');
            app.TRAINButton = uibutton(app.UseMachineLearningtoImproveYourEstimatePanel, 'push');
            app.TRAINButton.ButtonPushedFcn = createCallbackFcn(app, @TRAINButtonPushed, true);
            app.TRAINButton.Text = 'TRAIN';
            app.InfoaboutlearningrateRangeLabel = uilabel(app.UseMachineLearningtoImproveYourEstimatePanel);
            app.InfoaboutlearningrateRangeLabel.Text = '[Info about learning rate Range]';
            app.IterationLabel = uilabel(app.UseMachineLearningtoImproveYourEstimatePanel);
            app.IterationLabel.Text = 'Iteration:';
            app.Iteration = uieditfield(app.UseMachineLearningtoImproveYourEstimatePanel, 'numeric');
            app.ErrorEditFieldLabel = uilabel(app.UseMachineLearningtoImproveYourEstimatePanel);
            app.ErrorEditFieldLabel.Text = 'Error:';
            app.ErrorOut = uieditfield(app.UseMachineLearningtoImproveYourEstimatePanel, 'numeric');
            app.ThetaLabel = uilabel(app.UseMachineLearningtoImproveYourEstimatePanel);
            app.ThetaLabel.Text = 'Theta:';
            app.ThetaOut = uieditfield(app.UseMachineLearningtoImproveYourEstimatePanel, 'numeric');
            app.OptimizationMethodButtonGroup = uibuttongroup(app.UseMachineLearningtoImproveYourEstimatePanel);
            app.OptimizationMethodButtonGroup.Title = 'Optimization Method';
            app.IterationsButton = uiradiobutton(app.OptimizationMethodButtonGroup);
            app.IterationsButton.Text = '';
            app.IterationsButton.Value = true;
            app.ErrorButton = uiradiobutton(app.OptimizationMethodButtonGroup);
            app.ErrorButton.Text = '';
            app.DesiredIterationsEditFieldLabel = uilabel(app.OptimizationMethodButtonGroup);
            app.DesiredIterationsEditFieldLabel.Text = 'Desired Iterations';
            app.DesiredIterations = uieditfield(app.OptimizationMethodButtonGroup, 'numeric');
            app.DesiredErrorEditFieldLabel = uilabel(app.OptimizationMethodButtonGroup);
            app.DesiredErrorEditFieldLabel.Text = 'Desired Error';
            app.DesiredErrorThreshold = uieditfield(app.OptimizationMethodButtonGroup, 'numeric');
            app.UIAxes = uiaxes(app.RightGridLayout);
            title(app.UIAxes, 'Housing Price Data')
            xlabel(app.UIAxes, 'Selected Feature')
            ylabel(app.UIAxes, 'House Price(in millions)')
            app.TabGroup = uitabgroup(app.OverallGridLayout);
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.DatasetDropDownLabel = uilabel(app.DataTab);
            app.DatasetDropDownLabel.Text = 'Dataset';
            app.DatasetDropDown = uidropdown(app.DataTab);
            app.DatasetDropDown.Items = {'Housing'};
            app.DatasetDropDown.Value = 'Housing';
            app.PickyourdatasetforanalysisLabel = uilabel(app.DataTab);
            app.PickyourdatasetforanalysisLabel.Text = 'Pick your dataset for analysis';
            app.NoteOnceyouchooseaLabel = uilabel(app.DataTab);
            app.NoteOnceyouchooseaLabel.Text = 'Note: Once you choose a ';
            app.datasetdontchangeitforLabel = uilabel(app.DataTab);
            app.datasetdontchangeitforLabel.Text = 'dataset, don''t change it for';
            app.thedurationofyourLabel = uilabel(app.DataTab);
            app.thedurationofyourLabel.Text = 'the duration of your';
            app.analysisLabel = uilabel(app.DataTab);
            app.analysisLabel.Text = 'analysis.';
            app.FeatureSelectioTab = uitab(app.TabGroup);
            app.FeatureSelectioTab.Title = 'Feature Selectio';
            app.FeaturestoExamineLabel = uilabel(app.FeatureSelectioTab);
            app.FeaturestoExamineLabel.Text = 'Features to Examine:';
            app.firstFeature = uidropdown(app.FeatureSelectioTab);
            app.firstFeature.Items = {'Population in Neighborhood', 'Median Income', 'Number of Bedrooms', 'Family Size'};
            app.firstFeature.ValueChangedFcn = createCallbackFcn(app, @firstFeatureValueChanged, true);
            app.firstFeature.Value = 'Population in Neighborhood';
            app.CombinewithAnotherLabel = uilabel(app.FeatureSelectioTab);
            app.CombinewithAnotherLabel.Text = 'Combine with Another';
            app.FeatureOptionalLabel = uilabel(app.FeatureSelectioTab);
            app.FeatureOptionalLabel.Text = 'Feature (Optional)';
            app.secondFeature = uidropdown(app.FeatureSelectioTab);
            app.secondFeature.Items = {'Population in Neighborhood', 'Median Income', 'Number of Bedrooms', 'Family Size'};
            app.secondFeature.ValueChangedFcn = createCallbackFcn(app, @secondFeatureValueChanged, true);
            app.secondFeature.Value = 'Population in Neighborhood';
            app.ScaleFeatureLabel = uilabel(app.FeatureSelectioTab);
            app.ScaleFeatureLabel.Text = 'Scale Feature';
            app.scaleFeature = uidropdown(app.FeatureSelectioTab);
            app.scaleFeature.Items = {'None', 'Normalize', 'Log'};
            app.scaleFeature.ValueChangedFcn = createCallbackFcn(app, @scaleFeatureValueChanged, true);
            app.scaleFeature.Value = 'None';
            app.WhattoPlotLabel = uilabel(app.FeatureSelectioTab);
            app.WhattoPlotLabel.Text = 'What to Plot';
            app.plotOptions = uidropdown(app.FeatureSelectioTab);
            app.plotOptions.Items = {'Feature Only', 'Against Price'};
            app.plotOptions.ValueChangedFcn = createCallbackFcn(app, @plotOptionsValueChanged, true);
            app.plotOptions.Value = 'Feature Only';
            app.OperationDropDownLabel = uilabel(app.FeatureSelectioTab);
            app.OperationDropDownLabel.Text = 'Operation';
            app.combineOperation = uidropdown(app.FeatureSelectioTab);
            app.combineOperation.Items = {'None', 'Add', 'Subtract', 'Multiply', 'Divide'};
            app.combineOperation.ValueChangedFcn = createCallbackFcn(app, @combineOperationValueChanged, true);
            app.combineOperation.Value = 'None';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = prototype2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end